classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ProcessDataTab              matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        StartAnimationPlotResultsButton  matlab.ui.control.StateButton
        EditField                   matlab.ui.control.EditField
        msecLabel                   matlab.ui.control.Label
        msecEditField               matlab.ui.control.EditField
        intermediatefreqLabel       matlab.ui.control.Label
        intermediatefreqEditField   matlab.ui.control.EditField
        SamplingFrequencyLabel      matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.EditField
        PRNLabel                    matlab.ui.control.Label
        PRNEditField                matlab.ui.control.EditField
        ProcessDataButton           matlab.ui.control.Button
        MSECtoavgLabel              matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        STATUSLabel                 matlab.ui.control.Label
        FrequencyDataTab            matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        Code_fq_axes                matlab.ui.control.UIAxes
        Tracked_Intfq_axes          matlab.ui.control.UIAxes
        AmplitudeDataTab            matlab.ui.container.Tab
        corrRes                     matlab.ui.control.UIAxes
        promq                       matlab.ui.control.UIAxes
        promi                       matlab.ui.control.UIAxes
        AnimationTab                matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        diri_axis                   matlab.ui.control.UIAxes
        refi_axis                   matlab.ui.control.UIAxes
        dirq_axis                   matlab.ui.control.UIAxes
        refq_axis                   matlab.ui.control.UIAxes
        refcomp_axis                matlab.ui.control.UIAxes
        refdef_axis                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %set variables to be used in functions that are not part of the
        %same call back
        e_i
        e_q
        p_i
        p_q
        l_i
        l_q
        carrierfq
        codefq
        msec_avg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessDataButton
        function StartProcessingButtonPushed(app, event)
            %function to control data proccessing
        
            %if button is pressed again change animation button back to red
            app.StartAnimationPlotResultsButton.BackgroundColor = [1 0 0];
            %ppause used so gui can update
            pause( 0.5 )
            app.StatusEditField.Value = "Processing Data"; %update status
            pause( 0.5 )
            %get values for inputs
            prn = str2num(app.PRNEditField.Value);
            fs = str2num(app.SamplingFrequencyEditField.Value);
            intfreq = str2num(app.intermediatefreqEditField.Value);
            msec_to_process = str2num(app.msecEditField.Value);
            %process data
            [app.e_i,app.e_q,app.p_i,app.p_q,app.l_i,app.l_q,app.carrierfq,app.codefq] = findandtrack(prn, msec_to_process, fs, intfreq);
            %change animation color to green update status
            app.StartAnimationPlotResultsButton.BackgroundColor = [0 1 0];
            app.StatusEditField.Value = "Data Processed";
            pause( 0.5 )
            app.StatusEditField.Value = "Plotting";
            %plot results with processed data
            plotresults(app.e_i,app.e_q,app.p_i,app.p_q,app.l_i,app.l_q,app.carrierfq,app.codefq,app.Code_fq_axes,app.Tracked_Intfq_axes, ...
                   app.corrRes, app.promi, app.promq)
           %set status
           app.StatusEditField.Value = "Plots created";
           pause( 0.5 ) %pause so gui can update
        end

        % Value changed function: StartAnimationPlotResultsButton
        function StartAnimationPlotResultsButtonValueChanged(app, event)
            if app.StartAnimationPlotResultsButton.BackgroundColor == [1 0 0]
                %if data isnt processed, update status
               pause( 0.5 ) %pause to help gui update
               app.StatusEditField.Value = "Data must be processed";
               pause( 0.5 )
            else %if proccessing has occured 
               %get msec to avg
               app.msec_avg = str2num(app.EditField.Value);
               app.StatusEditField.Value = "Animations created";
               
               pause(2) %give time until animations start
               corrplot(app.msec_avg,app.diri_axis,app.dirq_axis, app.refi_axis, app.refq_axis, app.refcomp_axis, app.refdef_axis)
               app.StatusEditField.Value = ""; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessDataTab = uitab(app.TabGroup);
            app.ProcessDataTab.Title = 'Process Data';
            app.GridLayout = uigridlayout(app.ProcessDataTab);
            app.StartAnimationPlotResultsButton = uibutton(app.GridLayout, 'state');
            app.StartAnimationPlotResultsButton.ValueChangedFcn = createCallbackFcn(app, @StartAnimationPlotResultsButtonValueChanged, true);
            app.StartAnimationPlotResultsButton.Text = {'Start Animation'; 'Plot Results'};
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.msecLabel = uilabel(app.GridLayout);
            app.msecLabel.Text = 'msec: ';
            app.msecEditField = uieditfield(app.GridLayout, 'text');
            app.intermediatefreqLabel = uilabel(app.GridLayout);
            app.intermediatefreqLabel.Text = 'intermediate freq: ';
            app.intermediatefreqEditField = uieditfield(app.GridLayout, 'text');
            app.SamplingFrequencyLabel = uilabel(app.GridLayout);
            app.SamplingFrequencyLabel.Text = 'Sampling Frequency: ';
            app.SamplingFrequencyEditField = uieditfield(app.GridLayout, 'text');
            app.PRNLabel = uilabel(app.GridLayout);
            app.PRNLabel.Text = 'PRN: ';
            app.PRNEditField = uieditfield(app.GridLayout, 'text');
            app.ProcessDataButton = uibutton(app.GridLayout, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.MSECtoavgLabel = uilabel(app.GridLayout);
            app.MSECtoavgLabel.Text = 'MSEC to avg: ';
            app.StatusEditField = uieditfield(app.GridLayout, 'text');
            app.STATUSLabel = uilabel(app.GridLayout);
            app.STATUSLabel.Text = 'STATUS:';
            app.FrequencyDataTab = uitab(app.TabGroup);
            app.FrequencyDataTab.Title = 'Frequency Data';
            app.GridLayout2 = uigridlayout(app.FrequencyDataTab);
            app.Code_fq_axes = uiaxes(app.GridLayout2);
            title(app.Code_fq_axes, 'Tracked Code Frequency ')
            xlabel(app.Code_fq_axes, 'milliseconds')
            ylabel(app.Code_fq_axes, 'Hz')
            app.Tracked_Intfq_axes = uiaxes(app.GridLayout2);
            title(app.Tracked_Intfq_axes, 'Tracked Intermediate Frequency')
            xlabel(app.Tracked_Intfq_axes, 'milliseconds')
            ylabel(app.Tracked_Intfq_axes, 'Hz')
            app.AmplitudeDataTab = uitab(app.TabGroup);
            app.AmplitudeDataTab.Title = 'Amplitude Data';
            app.corrRes = uiaxes(app.AmplitudeDataTab);
            title(app.corrRes, 'Correlation Results')
            xlabel(app.corrRes, 'milliseconds')
            ylabel(app.corrRes, 'amplitude')
            app.promq = uiaxes(app.AmplitudeDataTab);
            title(app.promq, 'Prompt I Channel')
            xlabel(app.promq, 'milliseconds')
            ylabel(app.promq, 'amplitude')
            app.promi = uiaxes(app.AmplitudeDataTab);
            title(app.promi, 'Prompt Q Channel')
            xlabel(app.promi, 'milliseconds')
            ylabel(app.promi, 'amplitude')
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.GridLayout3 = uigridlayout(app.AnimationTab);
            app.diri_axis = uiaxes(app.GridLayout3);
            title(app.diri_axis, 'Direct (I)')
            xlabel(app.diri_axis, '')
            ylabel(app.diri_axis, '')
            app.refi_axis = uiaxes(app.GridLayout3);
            title(app.refi_axis, 'Direct (Q)')
            xlabel(app.refi_axis, '')
            ylabel(app.refi_axis, '')
            app.dirq_axis = uiaxes(app.GridLayout3);
            title(app.dirq_axis, 'reflected/direct ratio:')
            xlabel(app.dirq_axis, '')
            ylabel(app.dirq_axis, '')
            app.refq_axis = uiaxes(app.GridLayout3);
            title(app.refq_axis, 'Reflect (I)')
            xlabel(app.refq_axis, '')
            ylabel(app.refq_axis, '')
            app.refcomp_axis = uiaxes(app.GridLayout3);
            title(app.refcomp_axis, 'Reflect (Q)')
            xlabel(app.refcomp_axis, '')
            ylabel(app.refcomp_axis, '')
            app.refdef_axis = uiaxes(app.GridLayout3);
            title(app.refdef_axis, {'reflected composite'; ' path delay of'})
            xlabel(app.refdef_axis, 'time elapsed')
            ylabel(app.refdef_axis, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end